classdef LegTeachApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        KinematicsSwitchLabel  matlab.ui.control.Label
        KinematicsSwitch       matlab.ui.control.RockerSwitch
        q1SpinnerLabel         matlab.ui.control.Label
        q1Spinner              matlab.ui.control.Spinner
        q2Label                matlab.ui.control.Label
        q2Spinner              matlab.ui.control.Spinner
        q3SpinnerLabel         matlab.ui.control.Label
        q3Spinner              matlab.ui.control.Spinner
        L1SpinnerLabel         matlab.ui.control.Label
        L1Spinner              matlab.ui.control.Spinner
        L2SpinnerLabel         matlab.ui.control.Label
        L2Spinner              matlab.ui.control.Spinner
        L3SpinnerLabel         matlab.ui.control.Label
        L3Spinner              matlab.ui.control.Spinner
        L4SpinnerLabel         matlab.ui.control.Label
        L4Spinner              matlab.ui.control.Spinner
        L5SpinnerLabel         matlab.ui.control.Label
        L5Spinner              matlab.ui.control.Spinner
        L6SpinnerLabel         matlab.ui.control.Label
        L6Spinner              matlab.ui.control.Spinner
        L7SpinnerLabel         matlab.ui.control.Label
        L7Spinner              matlab.ui.control.Spinner
        L8SpinnerLabel         matlab.ui.control.Label
        L8Spinner              matlab.ui.control.Spinner
        L9SpinnerLabel         matlab.ui.control.Label
        L9Spinner              matlab.ui.control.Spinner
        L10SpinnerLabel        matlab.ui.control.Label
        L10Spinner             matlab.ui.control.Spinner
        T1SpinnerLabel         matlab.ui.control.Label
        T1Spinner              matlab.ui.control.Spinner
        T2SpinnerLabel         matlab.ui.control.Label
        T2Spinner              matlab.ui.control.Spinner
        T3SpinnerLabel         matlab.ui.control.Label
        T3Spinner              matlab.ui.control.Spinner
        P0xSpinnerLabel        matlab.ui.control.Label
        P0xSpinner             matlab.ui.control.Spinner
        P0ySpinnerLabel        matlab.ui.control.Label
        P0ySpinner             matlab.ui.control.Spinner
        P4xSpinnerLabel        matlab.ui.control.Label
        P4xSpinner             matlab.ui.control.Spinner
        P4ySpinnerLabel        matlab.ui.control.Label
        P4ySpinner             matlab.ui.control.Spinner
        P4angleSpinnerLabel    matlab.ui.control.Label
        P4angleSpinner         matlab.ui.control.Spinner
        Label                  matlab.ui.control.Label
        P0xSpinner_2Label      matlab.ui.control.Label
        P0xSpinner_2           matlab.ui.control.Spinner
        P0ySpinner_2           matlab.ui.control.Spinner
        P0ySpinner_2Label      matlab.ui.control.Label
        P1xSpinner             matlab.ui.control.Spinner
        P1xSpinnerLabel        matlab.ui.control.Label
        P1ySpinner             matlab.ui.control.Spinner
        P1ySpinnerLabel        matlab.ui.control.Label
        P2xSpinner             matlab.ui.control.Spinner
        P2xSpinnerLabel        matlab.ui.control.Label
        P2ySpinner             matlab.ui.control.Spinner
        P2ySpinnerLabel        matlab.ui.control.Label
        P3xSpinner             matlab.ui.control.Spinner
        P3xSpinnerLabel        matlab.ui.control.Label
        P3ySpinner             matlab.ui.control.Spinner
        P3ySpinnerLabel        matlab.ui.control.Label
        P4xSpinner_2Label      matlab.ui.control.Label
        P4xSpinner_2           matlab.ui.control.Spinner
        P4ySpinner_2           matlab.ui.control.Spinner
        P4ySpinner_2Label      matlab.ui.control.Label
        P5xSpinner             matlab.ui.control.Spinner
        P5xSpinnerLabel        matlab.ui.control.Label
        P5ySpinner             matlab.ui.control.Spinner
        P5ySpinnerLabel        matlab.ui.control.Label
        P6xSpinner             matlab.ui.control.Spinner
        P6xSpinnerLabel        matlab.ui.control.Label
        P6ySpinner             matlab.ui.control.Spinner
        P6ySpinnerLabel        matlab.ui.control.Label
        P7xSpinner             matlab.ui.control.Spinner
        P7xSpinnerLabel        matlab.ui.control.Label
        P7ySpinner             matlab.ui.control.Spinner
        P7ySpinnerLabel        matlab.ui.control.Label
        P8xSpinner             matlab.ui.control.Spinner
        P8xSpinnerLabel        matlab.ui.control.Label
        P8ySpinner             matlab.ui.control.Spinner
        P8ySpinnerLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        initL1 = 100;
        initL2 = 300;
        initL3 = 303.5;
        initL4 = 100;
        initL5 = 180;
        initL6 = 22.563;
        initL7 = 110;
        initL8 = 105;
        initL9 = 20;
        initL10 = 25;
        initT1 = 22.563;
        initT2 = 22.563;
        initT3 = 20;
        initL = [100, 300, 303.5, 100, 180, 22.563, 110, 105, 20, 25, 22.563, 22.563, 20];
        
        p0 = [0, 0];
        lineMap = [0 1 ; 0 2 ; 0 5 ; 1 3 ; 2 3 ; 3 4 ; 5 6 ; 6 7 ; 7 8 ; 2 7 ; 8 4 ; 2 6];
        
        l;
        fk = true; 
        p;
        tau = 0;
        q = deg2rad([51.69, 120.206, 243.691]);
        footPose = [0, 0, 0];
    end
    
    methods (Access = private)
        
        function [p, tau] = forwardKinematics(app, l, p0, q)
            p(1, 1) = p0(1) + l(1) * cos(q(1));
            p(1, 2) = p0(2) - l(1) * sin(q(1));
            p(2, 1) = p0(1) + l(2) * cos(q(2));
            p(2, 2) = p0(2) - l(2) * sin(q(2));
            p(5, 1) = p0(1) + l(6) * cos(q(3));
            p(5, 2) = p0(1) - l(6) * sin(q(3));
            
            lr1 = app.distance(p(2, 1), p(1, 1), p(2, 2), p(1, 2));
            alpha(1) = app.angleCosineRule(l(1), l(2), lr1);
            alpha(2) = app.angleCosineRule(l(3), lr1, l(4));
            gamma(1) = app.angleCosineRule(lr1, l(2), l(1));
            beta(1) = pi - (alpha(1) + gamma(1));
            beta(2) = app.angleCosineRule(l(4), l(1), l(3));
            
            p(3, 1) = p(2, 1) + l(4) * cos(q(2) - (pi - (alpha(1) + alpha(2))));
            p(3, 2) = p(2, 2) - l(4) * sin(q(2) - (pi - (alpha(1) + alpha(2))));
            
            gamma(2) = pi - (alpha(2) + beta(2));
            
            p(4, 1) = p(2, 1) + (l(4) + l(5)) * cos(q(2) - (pi - (alpha(1) + alpha(2))));
            p(4, 2) = p(2, 2) - (l(4) + l(5)) * sin(q(2) - (pi - (alpha(1) + alpha(2))));
            
            lr2 = app.distance(p(5, 1), p(2, 1), p(5, 2), p(2, 2));
            delta(1) = app.angleCosineRule(l(2), l(6), lr2);
            delta(2) = app.angleCosineRule(l(11), l(7), lr2);
            
            p(6, 1) = p(5, 1) + l(7) * cos(q(3) - (pi - (delta(1) + delta(2))));
            p(6, 2) = p(5, 2) - l(7) * sin(q(3) - (pi - (delta(1) + delta(2))));
            
            omega(1) = app.angleCosineRule(l(11), l(12), l(13));
            omega(3) = app.angleCosineRule(l(13), l(12), l(11));
            omega(2) = pi - (omega(1) + omega(3));
            lambda = app.angleCosineRule(lr2, l(7), l(12));
            
            p(7, 1) = p(6, 1) + l(11) * cos(q(3) - (pi - (delta(1) + delta(2))) - (pi - (lambda + omega(3))));
            p(7, 2) = p(6, 2) - l(11) * sin(q(3) - (pi - (delta(1) + delta(2))) - (pi - (lambda + omega(3))));
            
            lr3 = app.distance(p(7, 1), p(4, 1), p(7, 2), p(4, 2));
            phi(1) = app.angleCosineRule((l(4) + l(5)), l(13), lr3);
            phi(2) = app.angleCosineRule(l(9), lr3, l(8));
            
            p(8, 1) = p(7, 1) + l(8) * cos(q(3) - (pi - (delta(1) + delta(2))) - (pi - (lambda + omega(3))) - (pi - (omega(2) + (phi(1) + phi(2)))));
            p(8, 2) = p(7, 2) - l(8) * sin(q(3) - (pi - (delta(1) + delta(2))) - (pi - (lambda + omega(3))) - (pi - (omega(2) + (phi(1) + phi(2)))));
            
            if (p(4, 2) > p(8, 2))
                if (p(4, 1) > p(8, 1))
                    tau = abs(atan((p(4, 2) - p(8, 2)) / (p(4, 1) - p(8, 1))));
                else
                    tau = pi - abs(atan((p(4, 2) - p(8, 2)) / (p(4, 1) - p(8, 1))));
                end
            else
                if (p(4, 1) > p(8, 1))
                    tau = -abs(atan((p(4, 2) - p(8, 2)) / (p(4, 1) - p(8, 1))));
                else
                    tau = -(pi - abs(atan((p(4, 2) - p(8, 2)) / (p(4, 1) - p(8, 1)))));
                end
            end
            p = [p0 ; p];
        end
        
        function [q, p] = inverseKinematics(app, l, p0, p4, tau)
            p(4, 1) = p4(1);
            p(4, 2) = p4(2);
            p(8, 1) = p(4, 1) - l(9) * cos(tau);
            p(8, 2) = p(4, 2) - l(9) * sin(tau);
            
            lr1 = app.distance(p0(1), p4(1), p0(2), p4(2));
            alpha = app.angleCosineRule(lr1, l(2), (l(4) + l(5)));
            lr2 = app.sideCosineRule(l(2), l(4), alpha);
            beta = app.angleCosineRule(lr2, l(1), l(3));
            delta(1) = app.angleCosineRule(l(2), l(4), lr2);
            delta(2) = app.angleCosineRule(l(1), lr2, l(3));
            gamma(1) = pi - (alpha + delta(1));
            gamma(2) = pi - (beta + delta(2));
            iota = app.angleCosineRule((l(4) + l(5)), l(2), lr1);
            sigma = abs(atan((p4(2) - p0(2)) / (p4(1) - p0(1))));
            theta = 0;
            
            if (p4(1) < p0(1))
                if (p4(2) < p0(2))
                    theta = sigma - iota;
                    q(2) = pi - (sigma - iota);
                else
                    theta = iota + sigma;
                    q(2) = pi + iota + sigma;
                end
            else
                if (p4(2) < p0(2))
                    theta = pi - (sigma + iota);
                    q(2) = sigma + iota;
                else
                    theta = pi - (iota - sigma);
                    q(2) = iota - sigma;
                end
            end
            
            q(1) = q(2) - (gamma(1) + gamma(2)); %or pi - (theta + gamma)
            
            p(1, 1) = p0(1) + l(1) * cos(q(1));
            p(1, 2) = p0(2) - l(1) * sin(q(1));
            p(2, 1) = p0(1) + l(2) * cos(q(2));
            p(2, 2) = p0(2) - l(2) * sin(q(2));
            p(3, 1) = p0(1) + lr2 * cos(q(1) + gamma(2));
            p(3, 2) = p0(2) - lr2 * sin(q(1) + gamma(2));
            
            lr3 = app.distance(p(2, 1), p(8, 1), p(2, 2), p(8, 2));
            w(1) = app.angleCosineRule((l(4) + l(5)), lr3, l(9));
            w(2) = app.angleCosineRule(l(13), l(8), lr3);
            
            p(7, 1) = p(8, 1) + l(8) * cos(tau + (w(1) + w(2)));
            p(7, 2) = p(8, 2) + l(8) * sin(tau + (w(1) + w(2)));
            
            omega(2) = app.angleCosineRule(l(12), l(11), l(13));
            omega(1) = app.angleCosineRule(l(11), l(12), l(13));
            omega(3) = pi - (omega(1) + omega(2));
            phi = app.angleCosineRule(lr3, l(13), l(8));
            
            p(6, 1) = p(7, 1) + l(11) * cos(tau + (w(1) + w(2)) - (pi - (omega(2) + phi)));
            p(6, 2) = p(7, 2) + l(11) * sin(tau + (w(1) + w(2)) - (pi - (omega(2) + phi)));
            
            lr4 = app.distance(p(6, 1), p0(1), p(6, 2), p0(2));
            lambda(1) = app.angleCosineRule(l(2), lr4, l(12));
            lambda(2) = app.angleCosineRule(l(6), lr4, l(7));
            
            p(5, 1) = p(6, 1) + l(7) * cos(tau + (w(1) + w(2)) - (pi - (omega(2) + phi)) - (pi - ((lambda(1) + lambda(2)) + omega(3))));
            p(5, 2) = p(6, 2) + l(7) * sin(tau + (w(1) + w(2)) - (pi - (omega(2) + phi)) - (pi - ((lambda(1) + lambda(2)) + omega(3))));
            
            epsilon = 0;
            if (p(5, 1) > p0(1))
                epsilon = pi - abs(atan((p(5, 2) - p0(2)) / (p(5, 1) - p0(1))));
            else
                epsilon = abs(atan((p(5, 2) - p0(2)) / (p(5, 1) - p0(1))));
            end
            
            if (p(5, 2) > p0(2))
                q(3) = pi + epsilon;
            else
                q(3) = pi - epsilon;
            end
            p = [p0 ; p];
        end
        
        %% Angle Cosine Rule
        function A = angleCosineRule(app, a, b, c)
            A = acos((b^2 + c^2 - a^2) / (2 * b * c));
        end
        
        %% Side Cosine Rule
        function a = sideCosineRule(app, b, c, A)
            a = sqrt(b^2 + c^2 - 2 * b * c * cos(A));
        end
       
        %% Distance
        function dist = distance(app, x1, x2, y1, y2)
            dist = abs(sqrt((y1 - y2)^2 + (x1 - x2)^2));
        end
        
        function simulate(app, p)
            plot(p(:, 1), p(:, 2), 'o', 'Parent', app.UIAxes);
            [numRows, numCols] = size(app.lineMap);
            for i = 1:numRows
               line([p(app.lineMap(i, 1) + 1, 1), p(app.lineMap(i, 2) + 1, 1)], [p(app.lineMap(i, 1) + 1, 2), p(app.lineMap(i, 2) + 1, 2)], 'Parent', app.UIAxes);
            end
            drawnow();
            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.PlotBoxAspectRatio = [3 4 4];
            
        end
        
        function updatePoints(app, points)
            app.P0xSpinner_2.Value = points(1, 1);
            app.P0ySpinner_2.Value = points(1, 2);
            app.P1xSpinner.Value = points(2, 1);
            app.P1ySpinner.Value = points(2, 2);
            app.P2xSpinner.Value = points(3, 1);
            app.P2ySpinner.Value = points(3, 2);
            app.P3xSpinner.Value = points(4, 1);
            app.P3ySpinner.Value = points(4, 2);
            app.P4xSpinner_2.Value = points(5, 1);
            app.P4ySpinner_2.Value = points(5, 2);
            app.P5xSpinner.Value = points(6, 1);
            app.P5ySpinner.Value = points(6, 2);
            app.P6xSpinner.Value = points(7, 1);
            app.P6ySpinner.Value = points(7, 2);
            app.P7xSpinner.Value = points(8, 1);
            app.P7ySpinner.Value = points(8, 2);
            app.P8xSpinner.Value = points(9, 1);
            app.P8ySpinner.Value = points(9, 2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.l = app.initL;
            app.L1Spinner.Value = app.l(1);
            app.L2Spinner.Value = app.l(2);
            app.L3Spinner.Value = app.l(3);
            app.L4Spinner.Value = app.l(4);
            app.L5Spinner.Value = app.l(5);
            app.L6Spinner.Value = app.l(6);
            app.L7Spinner.Value = app.l(7);
            app.L8Spinner.Value = app.l(8);
            app.L9Spinner.Value = app.l(9);
            app.L10Spinner.Value = app.l(10);
            app.T1Spinner.Value = app.l(11);
            app.T2Spinner.Value = app.l(12);
            app.T3Spinner.Value = app.l(13);
            
            app.q1Spinner.Value = app.q(1);
            app.q2Spinner.Value = app.q(2);
            app.q3Spinner.Value = app.q(3);
            app.P0xSpinner.Value = app.p0(1);
            app.P0ySpinner.Value = app.p0(2);
            
            app.P0xSpinner_2.Enable = false;
            app.P0ySpinner_2.Enable = false;
            app.P1xSpinner.Enable = false;
            app.P1ySpinner.Enable = false;
            app.P2xSpinner.Enable = false;
            app.P2ySpinner.Enable = false;
            app.P3xSpinner.Enable = false;
            app.P3ySpinner.Enable = false;
            app.P4xSpinner_2.Enable = false;
            app.P4ySpinner_2.Enable = false;
            app.P5xSpinner.Enable = false;
            app.P5ySpinner.Enable = false;
            app.P6xSpinner.Enable = false;
            app.P6ySpinner.Enable = false;
            app.P7xSpinner.Enable = false;
            app.P7ySpinner.Enable = false;
            app.P8xSpinner.Enable = false;
            app.P8ySpinner.Enable = false;
            
            if (app.KinematicsSwitch.Value == "Forward")
                app.fk = true;
                app.q1Spinner.Enable = true;
                app.q2Spinner.Enable = true;
                app.q3Spinner.Enable = true;
                app.P4xSpinner.Enable = false;
                app.P4ySpinner.Enable = false;
                app.P4angleSpinner.Enable = false;
                [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
                app.footPose = [app.p(5, 1), app.p(5, 2)];
                app.P4xSpinner.Value = app.footPose(1);
                app.P4ySpinner.Value = app.footPose(2);
                app.P4angleSpinner.Value = app.tau;
                app.simulate(app.p);
            else
                app.fk = false;
                app.q1Spinner.Enable = false;
                app.q2Spinner.Enable = false;
                app.q3Spinner.Enable = false;
                app.P4xSpinner.Enable = true;
                app.P4ySpinner.Enable = true;
                app.P4angleSpinner.Enable = true;
            end
        end

        % Callback function
        function ButtonPushed(app, event)
            [p, angles] = app.forwardKinematics(app.l, app.p0, app.q);
            testPoint = [-70.046, -90.57];
            testTau = angles;
            [outQ, outP] = app.inverseKinematics(app.l, app.p0, testPoint, testTau);
            plot(outP(:, 1), outP(:, 2), 'o', 'Parent', app.UIAxes);
            [numRows, numCols] = size(app.lineMap);
            for i = 1:numRows
               line([outP(app.lineMap(i, 1) + 1, 1), outP(app.lineMap(i, 2) + 1, 1)], [outP(app.lineMap(i, 1) + 1, 2), outP(app.lineMap(i, 2) + 1, 2)], 'Parent', app.UIAxes);
            end
            drawnow();
        end

        % Value changed function: KinematicsSwitch
        function KinematicsSwitchValueChanged(app, event)
            value = app.KinematicsSwitch.Value;
            if (value == "Forward")
                app.fk = true;
                app.q1Spinner.Enable = true;
                app.q2Spinner.Enable = true;
                app.q3Spinner.Enable = true;
                app.P4xSpinner.Enable = false;
                app.P4ySpinner.Enable = false;
                app.P4angleSpinner.Enable = false;
                app.q
                [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
                app.updatePoints(app.p);
                app.simulate(app.p);
            else
                app.fk = false;
                app.q1Spinner.Enable = false;
                app.q2Spinner.Enable = false;
                app.q3Spinner.Enable = false;
                app.P4xSpinner.Enable = true;
                app.P4ySpinner.Enable = true;
                app.P4angleSpinner.Enable = true;
                disp("false");
            end
        end

        % Value changed function: q1Spinner
        function q1SpinnerValueChanged(app, event)
            value = app.q1Spinner.Value;
            app.q(1) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.footPose = [app.p(5, 1), app.p(5, 2)];
            app.P4xSpinner.Value = app.footPose(1);
            app.P4ySpinner.Value = app.footPose(2);
            app.P4angleSpinner.Value = app.tau;
            app.updatePoints(app.p);
            app.simulate(app.p);
            
        end

        % Value changing function: q1Spinner
        function q1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.q(1) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.footPose = [app.p(5, 1), app.p(5, 2)];
            app.P4xSpinner.Value = app.footPose(1);
            app.P4ySpinner.Value = app.footPose(2);
            app.P4angleSpinner.Value = app.tau;
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: q2Spinner
        function q2SpinnerValueChanged(app, event)
            value = app.q2Spinner.Value;
            app.q(2) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.footPose = [app.p(5, 1), app.p(5, 2)];
            app.P4xSpinner.Value = app.footPose(1);
            app.P4ySpinner.Value = app.footPose(2);
            app.P4angleSpinner.Value = app.tau;
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: q2Spinner
        function q2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.q(2) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.footPose = [app.p(5, 1), app.p(5, 2)];
            app.P4xSpinner.Value = app.footPose(1);
            app.P4ySpinner.Value = app.footPose(2);
            app.P4angleSpinner.Value = app.tau;
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: q3Spinner
        function q3SpinnerValueChanged(app, event)
            value = app.q3Spinner.Value;
            app.q(3) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.footPose = [app.p(5, 1), app.p(5, 2)];
            app.P4xSpinner.Value = app.footPose(1);
            app.P4ySpinner.Value = app.footPose(2);
            app.P4angleSpinner.Value = app.tau;
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: q3Spinner
        function q3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.q(3) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.footPose = [app.p(5, 1), app.p(5, 2)];
            app.P4xSpinner.Value = app.footPose(1);
            app.P4ySpinner.Value = app.footPose(2);
            app.P4angleSpinner.Value = app.tau;
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: P4xSpinner
        function P4xSpinnerValueChanged(app, event)
            value = app.P4xSpinner.Value;
            app.footPose(1) = value;
            [app.q, app.p] = app.inverseKinematics(app.l, app.p0, app.footPose, app.tau);
            app.q1Spinner.Value = app.q(1);
            app.q2Spinner.Value = app.q(2);
            app.q3Spinner.Value = app.q(3);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: P4xSpinner
        function P4xSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.footPose(1) = changingValue;
            [app.q, app.p] = app.inverseKinematics(app.l, app.p0, app.footPose, app.tau);
            app.q1Spinner.Value = app.q(1);
            app.q2Spinner.Value = app.q(2);
            app.q3Spinner.Value = app.q(3);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: P4ySpinner
        function P4ySpinnerValueChanged(app, event)
            value = app.P4ySpinner.Value;
            app.footPose(2) = value;
            [app.q, app.p] = app.inverseKinematics(app.l, app.p0, app.footPose, app.tau);
            app.q1Spinner.Value = app.q(1);
            app.q2Spinner.Value = app.q(2);
            app.q3Spinner.Value = app.q(3);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: P4ySpinner
        function P4ySpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.footPose(2) = changingValue;
            [app.q, app.p] = app.inverseKinematics(app.l, app.p0, app.footPose, app.tau);
            app.q1Spinner.Value = app.q(1);
            app.q2Spinner.Value = app.q(2);
            app.q3Spinner.Value = app.q(3);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: P4angleSpinner
        function P4angleSpinnerValueChanged(app, event)
            value = app.P4angleSpinner.Value;
            app.tau = value;
            [app.q, app.p] = app.inverseKinematics(app.l, app.p0, app.footPose, app.tau);
            app.q1Spinner.Value = app.q(1);
            app.q2Spinner.Value = app.q(2);
            app.q3Spinner.Value = app.q(3);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: P4angleSpinner
        function P4angleSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.tau = changingValue;
            [app.q, app.p] = app.inverseKinematics(app.l, app.p0, app.footPose, app.tau);
            app.q1Spinner.Value = app.q(1);
            app.q2Spinner.Value = app.q(2);
            app.q3Spinner.Value = app.q(3);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: L1Spinner
        function L1SpinnerValueChanged(app, event)
            value = app.L1Spinner.Value;
            app.l(1) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.simulate(app.p);
        end

        % Value changing function: L1Spinner
        function L1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(1) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: L2Spinner
        function L2SpinnerValueChanged(app, event)
            value = app.L2Spinner.Value;
            app.l(2) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: L2Spinner
        function L2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(2) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: L3Spinner
        function L3SpinnerValueChanged(app, event)
            value = app.L3Spinner.Value;
            app.l(3) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: L3Spinner
        function L3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(3) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: L4Spinner
        function L4SpinnerValueChanged(app, event)
            value = app.L4Spinner.Value;
            app.l(4) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: L4Spinner
        function L4SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(4) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: L5Spinner
        function L5SpinnerValueChanged(app, event)
            value = app.L5Spinner.Value;
            app.l(5) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: L5Spinner
        function L5SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(5) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: L6Spinner
        function L6SpinnerValueChanged(app, event)
            value = app.L6Spinner.Value;
            app.l(6) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: L6Spinner
        function L6SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(6) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: L7Spinner
        function L7SpinnerValueChanged(app, event)
            value = app.L7Spinner.Value;
            app.l(7) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: L7Spinner
        function L7SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(7) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: L8Spinner
        function L8SpinnerValueChanged(app, event)
            value = app.L8Spinner.Value;
            app.l(8) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: L8Spinner
        function L8SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(8) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: L9Spinner
        function L9SpinnerValueChanged(app, event)
            value = app.L9Spinner.Value;
            app.l(9) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: L9Spinner
        function L9SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(9) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: L10Spinner
        function L10SpinnerValueChanged(app, event)
            value = app.L10Spinner.Value;
            app.l(10) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: L10Spinner
        function L10SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(10) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: T1Spinner
        function T1SpinnerValueChanged(app, event)
            value = app.T1Spinner.Value;
            app.l(11) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: T1Spinner
        function T1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(11) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: T2Spinner
        function T2SpinnerValueChanged(app, event)
            value = app.T2Spinner.Value;
            app.l(12) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: T2Spinner
        function T2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(12) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: T3Spinner
        function T3SpinnerValueChanged(app, event)
            value = app.T3Spinner.Value;
            app.l(13) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: T3Spinner
        function T3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.l(13) = changingValue;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changed function: P0xSpinner
        function P0xSpinnerValueChanged(app, event)
            value = app.P0xSpinner.Value;
            app.p0(1) = value;
            [app.p, app.tau] = app.forwardKinematics(app.l, app.p0, app.q);
            app.footPose = [app.p(5, 1), app.p(5, 2)];
            app.P4xSpinner.Value = app.footPose(1);
            app.P4ySpinner.Value = app.footPose(2);
            app.P4angleSpinner.Value = app.tau;
            app.p
            app.updatePoints(app.p);
            app.simulate(app.p);
        end

        % Value changing function: P0xSpinner
        function P0xSpinnerValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: P0ySpinner
        function P0ySpinnerValueChanged(app, event)
            value = app.P0ySpinner.Value;
            
        end

        % Value changing function: P0ySpinner
        function P0ySpinnerValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.KinematicsSwitchLabel = uilabel(app.UIFigure);
            app.KinematicsSwitchLabel.Text = 'Kinematics';
            app.KinematicsSwitch = uiswitch(app.UIFigure, 'rocker');
            app.KinematicsSwitch.Items = {'Inverse', 'Forward'};
            app.KinematicsSwitch.ValueChangedFcn = createCallbackFcn(app, @KinematicsSwitchValueChanged, true);
            app.KinematicsSwitch.Value = 'Forward';
            app.q1SpinnerLabel = uilabel(app.UIFigure);
            app.q1SpinnerLabel.Text = {'q1'; ''};
            app.q1Spinner = uispinner(app.UIFigure);
            app.q1Spinner.Step = 0.05;
            app.q1Spinner.ValueChangingFcn = createCallbackFcn(app, @q1SpinnerValueChanging, true);
            app.q1Spinner.ValueChangedFcn = createCallbackFcn(app, @q1SpinnerValueChanged, true);
            app.q2Label = uilabel(app.UIFigure);
            app.q2Label.Text = 'q2';
            app.q2Spinner = uispinner(app.UIFigure);
            app.q2Spinner.Step = 0.05;
            app.q2Spinner.ValueChangingFcn = createCallbackFcn(app, @q2SpinnerValueChanging, true);
            app.q2Spinner.ValueChangedFcn = createCallbackFcn(app, @q2SpinnerValueChanged, true);
            app.q3SpinnerLabel = uilabel(app.UIFigure);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.UIFigure);
            app.q3Spinner.Step = 0.05;
            app.q3Spinner.ValueChangingFcn = createCallbackFcn(app, @q3SpinnerValueChanging, true);
            app.q3Spinner.ValueChangedFcn = createCallbackFcn(app, @q3SpinnerValueChanged, true);
            app.L1SpinnerLabel = uilabel(app.UIFigure);
            app.L1SpinnerLabel.Text = 'L1';
            app.L1Spinner = uispinner(app.UIFigure);
            app.L1Spinner.ValueChangingFcn = createCallbackFcn(app, @L1SpinnerValueChanging, true);
            app.L1Spinner.ValueChangedFcn = createCallbackFcn(app, @L1SpinnerValueChanged, true);
            app.L2SpinnerLabel = uilabel(app.UIFigure);
            app.L2SpinnerLabel.Text = 'L2';
            app.L2Spinner = uispinner(app.UIFigure);
            app.L2Spinner.ValueChangingFcn = createCallbackFcn(app, @L2SpinnerValueChanging, true);
            app.L2Spinner.ValueChangedFcn = createCallbackFcn(app, @L2SpinnerValueChanged, true);
            app.L3SpinnerLabel = uilabel(app.UIFigure);
            app.L3SpinnerLabel.Text = 'L3';
            app.L3Spinner = uispinner(app.UIFigure);
            app.L3Spinner.ValueChangingFcn = createCallbackFcn(app, @L3SpinnerValueChanging, true);
            app.L3Spinner.ValueChangedFcn = createCallbackFcn(app, @L3SpinnerValueChanged, true);
            app.L4SpinnerLabel = uilabel(app.UIFigure);
            app.L4SpinnerLabel.Text = 'L4';
            app.L4Spinner = uispinner(app.UIFigure);
            app.L4Spinner.ValueChangingFcn = createCallbackFcn(app, @L4SpinnerValueChanging, true);
            app.L4Spinner.ValueChangedFcn = createCallbackFcn(app, @L4SpinnerValueChanged, true);
            app.L5SpinnerLabel = uilabel(app.UIFigure);
            app.L5SpinnerLabel.Text = 'L5';
            app.L5Spinner = uispinner(app.UIFigure);
            app.L5Spinner.ValueChangingFcn = createCallbackFcn(app, @L5SpinnerValueChanging, true);
            app.L5Spinner.ValueChangedFcn = createCallbackFcn(app, @L5SpinnerValueChanged, true);
            app.L6SpinnerLabel = uilabel(app.UIFigure);
            app.L6SpinnerLabel.Text = 'L6';
            app.L6Spinner = uispinner(app.UIFigure);
            app.L6Spinner.ValueChangingFcn = createCallbackFcn(app, @L6SpinnerValueChanging, true);
            app.L6Spinner.ValueChangedFcn = createCallbackFcn(app, @L6SpinnerValueChanged, true);
            app.L7SpinnerLabel = uilabel(app.UIFigure);
            app.L7SpinnerLabel.Text = 'L7';
            app.L7Spinner = uispinner(app.UIFigure);
            app.L7Spinner.ValueChangingFcn = createCallbackFcn(app, @L7SpinnerValueChanging, true);
            app.L7Spinner.ValueChangedFcn = createCallbackFcn(app, @L7SpinnerValueChanged, true);
            app.L8SpinnerLabel = uilabel(app.UIFigure);
            app.L8SpinnerLabel.Text = 'L8';
            app.L8Spinner = uispinner(app.UIFigure);
            app.L8Spinner.ValueChangingFcn = createCallbackFcn(app, @L8SpinnerValueChanging, true);
            app.L8Spinner.ValueChangedFcn = createCallbackFcn(app, @L8SpinnerValueChanged, true);
            app.L9SpinnerLabel = uilabel(app.UIFigure);
            app.L9SpinnerLabel.Text = 'L9';
            app.L9Spinner = uispinner(app.UIFigure);
            app.L9Spinner.ValueChangingFcn = createCallbackFcn(app, @L9SpinnerValueChanging, true);
            app.L9Spinner.ValueChangedFcn = createCallbackFcn(app, @L9SpinnerValueChanged, true);
            app.L10SpinnerLabel = uilabel(app.UIFigure);
            app.L10SpinnerLabel.Text = 'L10';
            app.L10Spinner = uispinner(app.UIFigure);
            app.L10Spinner.ValueChangingFcn = createCallbackFcn(app, @L10SpinnerValueChanging, true);
            app.L10Spinner.ValueChangedFcn = createCallbackFcn(app, @L10SpinnerValueChanged, true);
            app.T1SpinnerLabel = uilabel(app.UIFigure);
            app.T1SpinnerLabel.Text = 'T1';
            app.T1Spinner = uispinner(app.UIFigure);
            app.T1Spinner.ValueChangingFcn = createCallbackFcn(app, @T1SpinnerValueChanging, true);
            app.T1Spinner.ValueChangedFcn = createCallbackFcn(app, @T1SpinnerValueChanged, true);
            app.T2SpinnerLabel = uilabel(app.UIFigure);
            app.T2SpinnerLabel.Text = 'T2';
            app.T2Spinner = uispinner(app.UIFigure);
            app.T2Spinner.ValueChangingFcn = createCallbackFcn(app, @T2SpinnerValueChanging, true);
            app.T2Spinner.ValueChangedFcn = createCallbackFcn(app, @T2SpinnerValueChanged, true);
            app.T3SpinnerLabel = uilabel(app.UIFigure);
            app.T3SpinnerLabel.Text = 'T3';
            app.T3Spinner = uispinner(app.UIFigure);
            app.T3Spinner.ValueChangingFcn = createCallbackFcn(app, @T3SpinnerValueChanging, true);
            app.T3Spinner.ValueChangedFcn = createCallbackFcn(app, @T3SpinnerValueChanged, true);
            app.P0xSpinnerLabel = uilabel(app.UIFigure);
            app.P0xSpinnerLabel.Text = 'P0 x';
            app.P0xSpinner = uispinner(app.UIFigure);
            app.P0xSpinner.ValueChangingFcn = createCallbackFcn(app, @P0xSpinnerValueChanging, true);
            app.P0xSpinner.ValueChangedFcn = createCallbackFcn(app, @P0xSpinnerValueChanged, true);
            app.P0ySpinnerLabel = uilabel(app.UIFigure);
            app.P0ySpinnerLabel.Text = 'P0 y';
            app.P0ySpinner = uispinner(app.UIFigure);
            app.P0ySpinner.ValueChangingFcn = createCallbackFcn(app, @P0ySpinnerValueChanging, true);
            app.P0ySpinner.ValueChangedFcn = createCallbackFcn(app, @P0ySpinnerValueChanged, true);
            app.P4xSpinnerLabel = uilabel(app.UIFigure);
            app.P4xSpinnerLabel.Text = 'P4 x';
            app.P4xSpinner = uispinner(app.UIFigure);
            app.P4xSpinner.ValueChangingFcn = createCallbackFcn(app, @P4xSpinnerValueChanging, true);
            app.P4xSpinner.ValueChangedFcn = createCallbackFcn(app, @P4xSpinnerValueChanged, true);
            app.P4ySpinnerLabel = uilabel(app.UIFigure);
            app.P4ySpinnerLabel.Text = 'P4 y';
            app.P4ySpinner = uispinner(app.UIFigure);
            app.P4ySpinner.ValueChangingFcn = createCallbackFcn(app, @P4ySpinnerValueChanging, true);
            app.P4ySpinner.ValueChangedFcn = createCallbackFcn(app, @P4ySpinnerValueChanged, true);
            app.P4angleSpinnerLabel = uilabel(app.UIFigure);
            app.P4angleSpinnerLabel.Text = 'P4 angle';
            app.P4angleSpinner = uispinner(app.UIFigure);
            app.P4angleSpinner.Step = 0.05;
            app.P4angleSpinner.ValueChangingFcn = createCallbackFcn(app, @P4angleSpinnerValueChanging, true);
            app.P4angleSpinner.ValueChangedFcn = createCallbackFcn(app, @P4angleSpinnerValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.P0xSpinner_2Label = uilabel(app.UIFigure);
            app.P0xSpinner_2Label.Text = 'P0 x';
            app.P0xSpinner_2 = uispinner(app.UIFigure);
            app.P0ySpinner_2 = uispinner(app.UIFigure);
            app.P0ySpinner_2Label = uilabel(app.UIFigure);
            app.P0ySpinner_2Label.Text = 'P0 y';
            app.P1xSpinner = uispinner(app.UIFigure);
            app.P1xSpinnerLabel = uilabel(app.UIFigure);
            app.P1xSpinnerLabel.Text = 'P1 x';
            app.P1ySpinner = uispinner(app.UIFigure);
            app.P1ySpinnerLabel = uilabel(app.UIFigure);
            app.P1ySpinnerLabel.Text = 'P1 y';
            app.P2xSpinner = uispinner(app.UIFigure);
            app.P2xSpinnerLabel = uilabel(app.UIFigure);
            app.P2xSpinnerLabel.Text = 'P2 x';
            app.P2ySpinner = uispinner(app.UIFigure);
            app.P2ySpinnerLabel = uilabel(app.UIFigure);
            app.P2ySpinnerLabel.Text = 'P2 y';
            app.P3xSpinner = uispinner(app.UIFigure);
            app.P3xSpinnerLabel = uilabel(app.UIFigure);
            app.P3xSpinnerLabel.Text = 'P3 x';
            app.P3ySpinner = uispinner(app.UIFigure);
            app.P3ySpinnerLabel = uilabel(app.UIFigure);
            app.P3ySpinnerLabel.Text = 'P3 y';
            app.P4xSpinner_2Label = uilabel(app.UIFigure);
            app.P4xSpinner_2Label.Text = 'P4 x';
            app.P4xSpinner_2 = uispinner(app.UIFigure);
            app.P4ySpinner_2 = uispinner(app.UIFigure);
            app.P4ySpinner_2Label = uilabel(app.UIFigure);
            app.P4ySpinner_2Label.Text = 'P4 y';
            app.P5xSpinner = uispinner(app.UIFigure);
            app.P5xSpinnerLabel = uilabel(app.UIFigure);
            app.P5xSpinnerLabel.Text = 'P5 x';
            app.P5ySpinner = uispinner(app.UIFigure);
            app.P5ySpinnerLabel = uilabel(app.UIFigure);
            app.P5ySpinnerLabel.Text = 'P5 y';
            app.P6xSpinner = uispinner(app.UIFigure);
            app.P6xSpinnerLabel = uilabel(app.UIFigure);
            app.P6xSpinnerLabel.Text = 'P6 x';
            app.P6ySpinner = uispinner(app.UIFigure);
            app.P6ySpinnerLabel = uilabel(app.UIFigure);
            app.P6ySpinnerLabel.Text = 'P6 y';
            app.P7xSpinner = uispinner(app.UIFigure);
            app.P7xSpinnerLabel = uilabel(app.UIFigure);
            app.P7xSpinnerLabel.Text = 'P7 x';
            app.P7ySpinner = uispinner(app.UIFigure);
            app.P7ySpinnerLabel = uilabel(app.UIFigure);
            app.P7ySpinnerLabel.Text = 'P7 y';
            app.P8xSpinner = uispinner(app.UIFigure);
            app.P8xSpinnerLabel = uilabel(app.UIFigure);
            app.P8xSpinnerLabel.Text = 'P8 x';
            app.P8ySpinner = uispinner(app.UIFigure);
            app.P8ySpinnerLabel = uilabel(app.UIFigure);
            app.P8ySpinnerLabel.Text = 'P8 y';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LegTeachApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end